classdef SAS_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SASUIFigure                   matlab.ui.Figure
        DatapathPanel                 matlab.ui.container.Panel
        SelectpathButton              matlab.ui.control.Button
        CalibrationunknowndataEditField  matlab.ui.control.EditField
        DetectionparametersPanel      matlab.ui.container.Panel
        DetectSwitchLabel             matlab.ui.control.Label
        DetectSwitch                  matlab.ui.control.Switch
        CamerapixelsizeLabel          matlab.ui.control.Label
        CameraPixelSizeEditField      matlab.ui.control.NumericEditField
        CameraquantumefficiencyLabel  matlab.ui.control.Label
        CameraQEEditField             matlab.ui.control.NumericEditField
        MaximumsigmaEditFieldLabel    matlab.ui.control.Label
        MaximumSigmaEditField         matlab.ui.control.NumericEditField
        CameraoffsetLabel             matlab.ui.control.Label
        CameraOffsetEditField         matlab.ui.control.NumericEditField
        CameraEMgainLabel             matlab.ui.control.Label
        CameraEMGainEditField         matlab.ui.control.NumericEditField
        ProcessingparametersPanel     matlab.ui.container.Panel
        ProcessSwitchLabel            matlab.ui.control.Label
        ProcessSwitch                 matlab.ui.control.Switch
        ROIradiusLabel                matlab.ui.control.Label
        ROIRadiusEditField            matlab.ui.control.NumericEditField
        AnnotationparameterPanel      matlab.ui.container.Panel
        AnnotateSwitchLabel           matlab.ui.control.Label
        AnnotateSwitch                matlab.ui.control.Switch
        MinimumpeakheightEditFieldLabel  matlab.ui.control.Label
        MinimumPeakHeightEditField    matlab.ui.control.NumericEditField
        AnalysisparametersPanel       matlab.ui.container.Panel
        AnalyzeLabel                  matlab.ui.control.Label
        AnalyzeSwitch                 matlab.ui.control.Switch
        Labelingeffeciency            matlab.ui.control.Label
        LabelingeffeciencyEditField   matlab.ui.control.NumericEditField
        MaximumGaussianmixturesEditFieldLabel  matlab.ui.control.Label
        MaximumGMMEditField           matlab.ui.control.NumericEditField
        RefineCheckBox                matlab.ui.control.CheckBox
        RunButton                     matlab.ui.control.Button
        msgBox                        matlab.ui.control.TextArea
        SaveparametersButton          matlab.ui.control.Button
        LoadparametersButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        param = struct();
        data = struct();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [currentFolder,~,~] = fileparts(mfilename('fullpath'));
            addpath(genpath(currentFolder));
        end

        % Button pushed function: SelectpathButton
        function SelectpathButtonPushed(app, event)
            path = uigetdir;
            app.CalibrationunknowndataEditField.Value = path;
        end

        % Callback function
        function SelectpathButton_2Pushed(app, event)
            path = uigetdir;
            app.UnknowndataEditField.Value = path;
        end

        % Callback function
        function SelectpathButton_3Pushed(app, event)
            [file,path] = uigetfile;
            app.AnnotationtrainingdatasetEditField.Value = fullfile(path,file);
        end

        % Callback function
        function SelectpathButton_4Pushed(app, event)
            [file,path] = uigetfile;
            app.RegressiontrainingdatasetEditField.Value = fullfile(path,file);
        end

        % Callback function
        function SelectpathButton_5Pushed(app, event)
            [file,path] = uigetfile;
            app.TrainedannotationnetworkEditField.Value = fullfile(path,file);
        end

        % Callback function
        function SelectpathButton_6Pushed(app, event)
            [file,path] = uigetfile;
            app.TrainedregressionnetworkEditField.Value = fullfile(path,file);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            main(app);
        end

        % Callback function
        function SelectpathButton_7Pushed(app, event)
            path = uigetdir;
            app.ExportpathEditField.Value = path;
        end

        % Button pushed function: SaveparametersButton
        function SaveparametersButtonPushed(app, event)
            saveParamAuxFn(app);
        end

        % Button pushed function: LoadparametersButton
        function LoadparametersButtonPushed(app, event)
            loadParamAuxFn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SASUIFigure = uifigure('Visible', 'off');
            app.SASUIFigure.Name = 'SAS';
            app.DatapathPanel = uipanel(app.SASUIFigure);
            app.DatapathPanel.Title = '1. Data path';
            app.SelectpathButton = uibutton(app.DatapathPanel, 'push');
            app.SelectpathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpathButtonPushed, true);
            app.SelectpathButton.Text = 'Select path';
            app.CalibrationunknowndataEditField = uieditfield(app.DatapathPanel, 'text');
            app.DetectionparametersPanel = uipanel(app.SASUIFigure);
            app.DetectionparametersPanel.Title = '2. Detection parameters';
            app.DetectSwitchLabel = uilabel(app.DetectionparametersPanel);
            app.DetectSwitchLabel.Text = 'Detect';
            app.DetectSwitch = uiswitch(app.DetectionparametersPanel, 'slider');
            app.DetectSwitch.Items = {'Y', 'N'};
            app.DetectSwitch.Value = 'Y';
            app.CamerapixelsizeLabel = uilabel(app.DetectionparametersPanel);
            app.CamerapixelsizeLabel.Text = 'Camera pixel size';
            app.CameraPixelSizeEditField = uieditfield(app.DetectionparametersPanel, 'numeric');
            app.CameraPixelSizeEditField.Limits = [0 Inf];
            app.CameraPixelSizeEditField.Value = 100;
            app.CameraquantumefficiencyLabel = uilabel(app.DetectionparametersPanel);
            app.CameraquantumefficiencyLabel.Text = 'Camera quantum efficiency';
            app.CameraQEEditField = uieditfield(app.DetectionparametersPanel, 'numeric');
            app.CameraQEEditField.Limits = [0 100];
            app.CameraQEEditField.Value = 95;
            app.MaximumsigmaEditFieldLabel = uilabel(app.DetectionparametersPanel);
            app.MaximumsigmaEditFieldLabel.Text = 'Maximum sigma';
            app.MaximumSigmaEditField = uieditfield(app.DetectionparametersPanel, 'numeric');
            app.MaximumSigmaEditField.Limits = [0 Inf];
            app.MaximumSigmaEditField.Value = 200;
            app.CameraoffsetLabel = uilabel(app.DetectionparametersPanel);
            app.CameraoffsetLabel.Text = 'Camera offset';
            app.CameraOffsetEditField = uieditfield(app.DetectionparametersPanel, 'numeric');
            app.CameraOffsetEditField.Limits = [1 Inf];
            app.CameraOffsetEditField.Value = 100;
            app.CameraEMgainLabel = uilabel(app.DetectionparametersPanel);
            app.CameraEMgainLabel.Text = 'Camera EM gain';
            app.CameraEMGainEditField = uieditfield(app.DetectionparametersPanel, 'numeric');
            app.CameraEMGainEditField.Limits = [1 Inf];
            app.CameraEMGainEditField.Value = 58.8;
            app.ProcessingparametersPanel = uipanel(app.SASUIFigure);
            app.ProcessingparametersPanel.Title = '3. Processing parameters';
            app.ProcessSwitchLabel = uilabel(app.ProcessingparametersPanel);
            app.ProcessSwitchLabel.Text = 'Process';
            app.ProcessSwitch = uiswitch(app.ProcessingparametersPanel, 'slider');
            app.ProcessSwitch.Items = {'Y', 'N'};
            app.ProcessSwitch.Value = 'Y';
            app.ROIradiusLabel = uilabel(app.ProcessingparametersPanel);
            app.ROIradiusLabel.Text = 'ROI radius';
            app.ROIRadiusEditField = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.ROIRadiusEditField.Limits = [1 10];
            app.ROIRadiusEditField.Value = 2;
            app.AnnotationparameterPanel = uipanel(app.SASUIFigure);
            app.AnnotationparameterPanel.Title = '4. Annotation parameter';
            app.AnnotateSwitchLabel = uilabel(app.AnnotationparameterPanel);
            app.AnnotateSwitchLabel.Text = 'Annotate';
            app.AnnotateSwitch = uiswitch(app.AnnotationparameterPanel, 'slider');
            app.AnnotateSwitch.Items = {'Y', 'N'};
            app.AnnotateSwitch.Value = 'Y';
            app.MinimumpeakheightEditFieldLabel = uilabel(app.AnnotationparameterPanel);
            app.MinimumpeakheightEditFieldLabel.Text = 'Minimum peak height';
            app.MinimumPeakHeightEditField = uieditfield(app.AnnotationparameterPanel, 'numeric');
            app.MinimumPeakHeightEditField.Limits = [0 100];
            app.MinimumPeakHeightEditField.Value = 100;
            app.AnalysisparametersPanel = uipanel(app.SASUIFigure);
            app.AnalysisparametersPanel.Title = '5. Analysis parameters';
            app.AnalyzeLabel = uilabel(app.AnalysisparametersPanel);
            app.AnalyzeLabel.Text = 'Analyze';
            app.AnalyzeSwitch = uiswitch(app.AnalysisparametersPanel, 'slider');
            app.AnalyzeSwitch.Items = {'Y', 'N'};
            app.AnalyzeSwitch.Value = 'Y';
            app.Labelingeffeciency = uilabel(app.AnalysisparametersPanel);
            app.Labelingeffeciency.Text = 'Labeling effeciency';
            app.LabelingeffeciencyEditField = uieditfield(app.AnalysisparametersPanel, 'numeric');
            app.LabelingeffeciencyEditField.Limits = [0 100];
            app.LabelingeffeciencyEditField.Value = 100;
            app.MaximumGaussianmixturesEditFieldLabel = uilabel(app.AnalysisparametersPanel);
            app.MaximumGaussianmixturesEditFieldLabel.Text = 'Maximum Gaussian mixtures';
            app.MaximumGMMEditField = uieditfield(app.AnalysisparametersPanel, 'numeric');
            app.MaximumGMMEditField.Limits = [1 30];
            app.MaximumGMMEditField.Value = 16;
            app.RefineCheckBox = uicheckbox(app.AnalysisparametersPanel);
            app.RefineCheckBox.Text = 'Refine';
            app.RunButton = uibutton(app.SASUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.msgBox = uitextarea(app.SASUIFigure);
            app.SaveparametersButton = uibutton(app.SASUIFigure, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersButtonPushed, true);
            app.SaveparametersButton.Text = {'Save parameters'; ''};
            app.LoadparametersButton = uibutton(app.SASUIFigure, 'push');
            app.LoadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersButtonPushed, true);
            app.LoadparametersButton.Text = {'Load parameters'; ''};
            % Show the figure after all components are created
            app.SASUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAS_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SASUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SASUIFigure)
        end
    end
end